classdef phasor_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhasorFigure     matlab.ui.Figure
        RealImage        matlab.ui.control.Image
        NegativeImage    matlab.ui.control.Image
        SineImage        matlab.ui.control.Image
        ImaginaryButton  matlab.ui.control.StateButton
        QuitButton       matlab.ui.control.StateButton
        FreezeButton     matlab.ui.control.StateButton
        NegativeButton   matlab.ui.control.StateButton
        HeadingLabel     matlab.ui.control.Label
        PhasorAxes       matlab.ui.control.UIAxes
        TimeAxes         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        phasorpar % Description
        status % Description
    end

    methods (Access = private)
        
        function results = CalculateAndPlot(app)
            app.status.stopped=0;

            %---- Initialise circle and time plot
            phi= linspace(0,2*pi, 100);
            zc = exp(1i*phi);            % Unit circle

            nTime = 200;
            t = ( 0 : nTime-1) *app.phasorpar.dt;
            c = zeros(size(t));         % Time trace of only zeros
            s = zeros(size(t));         

            % Draw initial figure
            plot( app.PhasorAxes, real(zc), imag(zc), 'k:' );  % Unit circle
            hold( app.PhasorAxes, "on" );
            plot( app.PhasorAxes, [-1 1], [0 0], 'k:' );       % Real axis
            plot( app.PhasorAxes, [0,0],[-1 1],  'k:' );       % Imaginary axis

            hphasor= plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], '.-',  'color', 'k', Marker='.'   );   % Positive phasor
            hnphasor=plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], '.-',  'color', app.phasorpar.color{3} );   % Negative phasor
            hcos=    plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], '.-',  'color', app.phasorpar.color{1} );   % Cosine phasor (real)
            hsin=    plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], '.-',  'color', app.phasorpar.color{2} );   % Sine phasor (Imaginary)
            hcline=  plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], 'k:');    % Projection to real axis
            hnline=  plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], 'k:');    % Projection from negative phasor to real axis
            hsline=  plot(app.PhasorAxes, [0, 0 ], [ 0, 0 ], 'k:');    % Projection to imaginary axis

            hctime =  plot(app.TimeAxes, t,c, 'color', app.phasorpar.color{1} );    % Time trace, cos-wave
            hold(app.TimeAxes,"on");
            hstime =  plot(app.TimeAxes, t,s, 'color', app.phasorpar.color{2} );    % Time trace, sine-wave

            %%--- Run continously to animate plots
            app.phasorpar.t=0;
            
            while not(app.QuitButton.Value)
                if not(app.FreezeButton.Value)
                    tic
                    % Calculate new values
                    app.phasorpar.z = exp( 2*pi*1i*app.phasorpar.f* app.phasorpar.t );
                    app.phasorpar.zn= exp(-2*pi*1i*app.phasorpar.f* app.phasorpar.t );

                    z = app.phasorpar.z;
                    zn= app.phasorpar.zn;
                    c=[ c(2:end) real(z)];
                    s=[ s(2:end) imag(z)];

                    % Update data in plots
                    hphasor.XData =[  0     real(z)  ];  hphasor.YData= [  0     imag(z) ];
                    hnphasor.XData=[  0     real(zn) ];  hnphasor.YData=[  0     imag(zn)];
                    hcos.XData    =[  0     real(z)  ];  hcos.YData =   [  0       0     ];
                    hsin.XData    =[  0        0     ];  hsin.YData =   [  0     imag(z) ];
                    hcline.XData  =[real(z)  real(z) ];  hcline.YData = [  0     imag(z) ];
                    hnline.XData  =[real(zn) real(zn)];  hnline.YData = [  0     imag(zn)];
                    hsline.XData  =[  0      real(z) ];  hsline.YData = [imag(z) imag(z) ];

                    hctime.YData= t; hctime.XData= c;
                    hstime.YData= t; hstime.XData= s;
                    set(app.TimeAxes, 'ylim', [min(t) max(t)]);

                    % Update time
                    app.phasorpar.t=app.phasorpar.t+app.phasorpar.dt;
                    t=t+app.phasorpar.dt;
                    
                end
                % Determine which plots to display
                set(hnphasor, 'Visible', app.NegativeButton.Value);
                set(hnline,   'Visible', app.NegativeButton.Value);
                set(hsin,     'Visible', app.ImaginaryButton.Value);
                set(hsline,   'Visible', app.ImaginaryButton.Value);
                set(hstime,   'Visible', app.ImaginaryButton.Value);

                set(app.NegativeImage, 'Visible', app.NegativeButton.Value  );
                set(app.SineImage,     'Visible', app.ImaginaryButton.Value );

                drawnow 

                tel=toc;
                pause(app.phasorpar.dt-tel);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.phasorpar.f  = 0.15;
            app.phasorpar.dt = 0.05;

            nTime = 3000;
            app.phasorpar.t=linspace( 0, 1, nTime);

            c = colororder;
            app.phasorpar.color= mat2cell( c, ones( length(c), 1 ), 3 );
            CalculateAndPlot( app );
        end

        % Callback function
        function AmplitudeASliderValueChanging(app, event)
            app.phasorpar.A =event.Value;
            CalculateAndPlot( app )
        end

        % Callback function
        function FrequencyfSliderValueChanged(app, event)
            app.phasorpar.f = event.Value;
            CalculateAndPlot( app )
        end

        % Callback function
        function PhaseSliderValueChanging(app, event)
            app.phasorpar.phi=event.Value;
            CalculateAndPlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PhasorFigure = uifigure('Visible', 'off');
            app.PhasorFigure.Name = 'MATLAB App';
            app.TimeAxes = uiaxes(app.PhasorFigure);
            zlabel(app.TimeAxes, 'Z')
            app.PhasorAxes = uiaxes(app.PhasorFigure);
            xlabel(app.PhasorAxes, 'Re\{z\}')
            ylabel(app.PhasorAxes, 'Im\{z\}')
            zlabel(app.PhasorAxes, 'Z')
            app.HeadingLabel = uilabel(app.PhasorFigure);
            app.HeadingLabel.Text = 'Phasors and Sine waves';
            app.NegativeButton = uibutton(app.PhasorFigure, 'state');
            app.NegativeButton.Text = 'Negative';
            app.FreezeButton = uibutton(app.PhasorFigure, 'state');
            app.FreezeButton.Text = 'Freeze';
            app.QuitButton = uibutton(app.PhasorFigure, 'state');
            app.QuitButton.Text = 'Quit';
            app.ImaginaryButton = uibutton(app.PhasorFigure, 'state');
            app.ImaginaryButton.Text = 'Imaginary';
            app.SineImage = uiimage(app.PhasorFigure);
            app.SineImage.ImageSource = fullfile(pathToMLAPP, 'sin_imaginary.png');
            app.NegativeImage = uiimage(app.PhasorFigure);
            app.NegativeImage.Visible = 'off';
            app.NegativeImage.ImageSource = fullfile(pathToMLAPP, 'cos_pos_neg.png');
            app.RealImage = uiimage(app.PhasorFigure);
            app.RealImage.ImageSource = fullfile(pathToMLAPP, 'cos_real.png');
            % Show the figure after all components are created
            app.PhasorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phasor_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhasorFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhasorFigure)
        end
    end
end